classdef threeD_veiwer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GeneratefigureButton      matlab.ui.control.Button
        PortDropDown              matlab.ui.control.DropDown
        PortDropDownLabel         matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
        RunButton                 matlab.ui.control.Button
        ScanQualityDropDown       matlab.ui.control.DropDown
        ScanQualityDropDownLabel  matlab.ui.control.Label
        ObjectDropDown            matlab.ui.control.DropDown
        ObjectDropDownLabel       matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ObjectDropDown
        function ObjectDropDownValueChanged(app, event)
            value = app.ObjectDropDown.Value;

            if value=="Cube"
                fv = stlread("cube.stl");
            elseif value == "Cylinder"
                fv = stlread("cylinder.stl");
            elseif value =="Femur"
                fv = stlread("femur.stl");
            end
            scatter3(app.UIAxes,fv.vertices(:,1),fv.vertices(:,2),fv.vertices(:,3));

        end

        % Callback function: ListBox, RunButton
        function RunButtonPushed(app, event)
            out = python('test_space.py','2 2');
            temp = cellstr(out);
            app.ListBox.Items(1) = temp(1);
        end

        % Button pushed function: GeneratefigureButton
        function GeneratefigureButtonPushed(app, event)
            value = app.ObjectDropDown.Value;

            if value=="Cube"
                fv = stlread("cube.stl");
            elseif value == "Cylinder"
                fv = stlread("cylinder.stl");
            elseif value =="Femur"
                fv = stlread("femur.stl");
            end

            figure
            patch(fv,'FaceColor',       [0.8 0.8 1.0], ...
            'EdgeColor',       'none',        ...
            'FaceLighting',    'gouraud',     ...
            'AmbientStrength', 0.15);

            camlight('headlight');
            material('dull');

            axis('image');
            view([-135 35]);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ObjectDropDownLabel = uilabel(app.LeftPanel);
            app.ObjectDropDownLabel.Text = 'Object';
            app.ObjectDropDown = uidropdown(app.LeftPanel);
            app.ObjectDropDown.Items = {'Cube', 'Femur', 'Cylinder'};
            app.ObjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectDropDownValueChanged, true);
            app.ObjectDropDown.Value = 'Cube';
            app.ScanQualityDropDownLabel = uilabel(app.LeftPanel);
            app.ScanQualityDropDownLabel.Text = 'Scan Quality';
            app.ScanQualityDropDown = uidropdown(app.LeftPanel);
            app.ScanQualityDropDown.Items = {'High', 'Medium', 'Low'};
            app.ScanQualityDropDown.Value = 'High';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.Items = {'item1'};
            app.ListBox.ItemsData = {'out'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.ListBox.Value = 'out';
            app.PortDropDownLabel = uilabel(app.LeftPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.LeftPanel);
            app.PortDropDown.Items = {'COM3', 'COM7'};
            app.PortDropDown.Value = 'COM3';
            app.GeneratefigureButton = uibutton(app.LeftPanel, 'push');
            app.GeneratefigureButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefigureButtonPushed, true);
            app.GeneratefigureButton.Text = 'Generate figure';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = threeD_veiwer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end